classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        plateRecognizerUI          matlab.ui.Figure
        plateNumberTitle           matlab.ui.control.Label
        originalFileNameTitle      matlab.ui.control.Label
        processLicensePlateButton  matlab.ui.control.Button
        licensePlateNumber         matlab.ui.control.Label
        originalImageName          matlab.ui.control.Label
        inputImageEditField        matlab.ui.control.EditField
        InputImageEditFieldLabel   matlab.ui.control.Label
        findFileButton             matlab.ui.control.Button
        originalImage              matlab.ui.control.Image
        originalFileDescription    matlab.ui.control.Label
        editedImage                matlab.ui.control.Image
        editedImageDescription     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CANNY_THRESHOLD = 0.5;
        CROP_THRESHOLD_DIVIDEND = 600;
        IGNORE_UNIMPORTANT_CHARACTERS = true;
        UNIMPORTANT_CHARACTERS = [" ","-","+","="];
        MODEL = "OCRModel/newBinarized.traineddata"

        platePath = "";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("Functions\");
        end

        % Button pushed function: findFileButton
        function findFileB(app, event)
            
            % Prompts user to enter a file
            [file,location] = uigetfile('*.jpg');
            
            % Search was canceled
            if(isnumeric(file))
                return;
            end

            % Update plate path for other functions
            app.platePath = strcat(location,file);

            % Update name label for input image
            [~,app.originalImageName.Text,~] = fileparts(app.platePath);

            % Update text on text input
            app.inputImageEditField.Value = app.platePath;

            % Update image on plate input
            app.originalImage.ImageSource = app.platePath;

        end

        % Value changed function: inputImageEditField
        function findFileT(app, event)
            
            value = app.inputImageEditField.Value;

            % Ignore empty inputs
            if(value == "")
                return;
            end
            
            try
                % Extract directory of input
                temp = dir(value);

                % Try make a full path and file to the input
                fullFile = fullfile(temp.folder,temp.name);

                % Extract name and extension
                [~,name,ext] = fileparts(fullFile);

                % Make sure extension is jpg
                if(ext ~= ".jpg")
                    msgbox("File '" + name + ext + "' must be a .jpg file.","Error","Error");
                    return;
                end
                
                % Update plate path for other functions
                app.platePath = fullFile;

                % Update name label for input image
                app.originalImageName.Text = name;

                % Update image on plate input
                app.originalImage.ImageSource = app.platePath;

            catch
                msgbox("File '" + value + "' does not exist or cannot be found.","Error","Error");
                return;
            end

        end

        % Button pushed function: processLicensePlateButton
        function getPlateNumber(app, event)
          
            % Reads image from plate path into variable

            if(app.platePath == "")
                msgbox("No plate was specified.","Error","Error");
                return;
            end

            processImage = imread(app.platePath);

            % Use back-end functions
            processImage = preprocessImgTxt(processImage,app.CANNY_THRESHOLD,app.CROP_THRESHOLD_DIVIDEND);

            % Change the image of the processed image

            [temp,map] = gray2ind(processImage,2);
            temp = ind2rgb(temp,map);

            app.editedImage.ImageSource = temp;

            % Use OCR to get text
            ocrText = ocr(processImage,LayoutAnalysis="line",Model=app.MODEL);

            % Now modify the text to make it more suitable for output
            ocrText = modifyText(ocrText);

            if(app.IGNORE_UNIMPORTANT_CHARACTERS)
                ocrText = ignoreChars(ocrText,app.UNIMPORTANT_CHARACTERS);
            end

            % Finally, display the plate number
            app.licensePlateNumber.Text = ocrText;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.plateRecognizerUI = uifigure('Visible', 'off');
            app.plateRecognizerUI.Name = 'US License Plate Number Recognizer';
            app.editedImageDescription = uilabel(app.plateRecognizerUI);
            app.editedImageDescription.Text = 'Image the OCR uses to get the plate number.';
            app.editedImage = uiimage(app.plateRecognizerUI);
            app.originalFileDescription = uilabel(app.plateRecognizerUI);
            app.originalFileDescription.Text = 'Original jpg image that the user inputs.';
            app.originalImage = uiimage(app.plateRecognizerUI);
            app.findFileButton = uibutton(app.plateRecognizerUI, 'push');
            app.findFileButton.ButtonPushedFcn = createCallbackFcn(app, @findFileB, true);
            app.findFileButton.Icon = fullfile(pathToMLAPP, 'folderIcon.png');
            app.findFileButton.Text = '';
            app.InputImageEditFieldLabel = uilabel(app.plateRecognizerUI);
            app.InputImageEditFieldLabel.Text = 'Input Image';
            app.inputImageEditField = uieditfield(app.plateRecognizerUI, 'text');
            app.inputImageEditField.ValueChangedFcn = createCallbackFcn(app, @findFileT, true);
            app.originalImageName = uilabel(app.plateRecognizerUI);
            app.originalImageName.Text = '[ Filename ]';
            app.licensePlateNumber = uilabel(app.plateRecognizerUI);
            app.licensePlateNumber.Text = '[ Plate Number ]';
            app.processLicensePlateButton = uibutton(app.plateRecognizerUI, 'push');
            app.processLicensePlateButton.ButtonPushedFcn = createCallbackFcn(app, @getPlateNumber, true);
            app.processLicensePlateButton.Text = {'Process'; 'License'; 'Plate'};
            app.originalFileNameTitle = uilabel(app.plateRecognizerUI);
            app.originalFileNameTitle.Text = 'Original File Name';
            app.plateNumberTitle = uilabel(app.plateRecognizerUI);
            app.plateNumberTitle.Text = 'Plate Number';
            % Show the figure after all components are created
            app.plateRecognizerUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.plateRecognizerUI)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.plateRecognizerUI)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.plateRecognizerUI)
        end
    end
end